classdef GUI_identification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSidenthomescreenUIFigure     matlab.ui.Figure
        generalGrid                   matlab.ui.container.GridLayout
        ResultsvisualizationButton    matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        ParametersettingsPanel        matlab.ui.container.Panel
        IdentificationsettingsButton  matlab.ui.control.Button
        mzROIextractionButton         matlab.ui.control.Button
        ExperimenttypePanel           matlab.ui.container.Panel
        SignalacquisitionListBox      matlab.ui.control.ListBox
        SignalacquisitionLabel        matlab.ui.control.Label
        ROIMCRresultsPanel            matlab.ui.container.Panel
        DropDown_mzROI                matlab.ui.control.DropDown
        DropDown_ST                   matlab.ui.control.DropDown
        mzROIarrayLabel               matlab.ui.control.Label
        MatrixSTLabel                 matlab.ui.control.Label
        title                         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             evalin('base','ROIMCR_identification.matrix.st="-";');  % by default the selected option
%             evalin('base','ROIMCR_identification.matrix.mzROI="-";');
            evalin('base','ROIMCR_identification.experiment=1;');
            evalin('base','ROIMCR_identification.settings_ROIMCRresults=[];');
            evalin('base','ROIMCR_identification.ROIMCR_signals=[];')
            evalin('base','ROIMCR_identification.settings_identification=[];');
            evalin('base','ROIMCR_identification.ROIMCR_identification=[];');
        end

        % Drop down opening function: DropDown_ST
        function DropDownOpening(app, event)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            cell_array(1) = {'Select ST matrix'};
            for i=2:length(vars)+1
                cell_array{i} = vars(i-1).name;
            end
            
            % Delete StartUp variable
            cell_array = erase(cell_array,'ROIMCR_identification');
            emptyCells = cellfun(@isempty, cell_array);
            nonEmptyCells = ~emptyCells;
            cell_array = cell_array(nonEmptyCells);

            app.DropDown_ST.Items = cell_array;
        end

        % Drop down opening function: DropDown_mzROI
        function DropDown_mzROIOpening(app, event)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            cell_array{1} = 'Select mzROI array';
            for i=2:length(vars)+1
                cell_array{i} = vars(i-1).name;
            end
            
            %Delete StartUp variable
            cell_array = erase(cell_array,'ROIMCR_identification');
            emptyCells = cellfun(@isempty, cell_array);
            nonEmptyCells = ~emptyCells;
            cell_array = cell_array(nonEmptyCells);

            app.DropDown_mzROI.Items = cell_array;
            
        end

        % Value changed function: DropDown_ST
        function DropDown_STValueChanged(app, event)
            value_st = app.DropDown_ST.Value;
            value_st = evalin('base', value_st);

            assignin('base','value_st',value_st);
            evalin('base','ROIMCR_identification.matrix.st=value_st;'); 
            evalin('base','clear value_st');
        end

        % Value changed function: DropDown_mzROI
        function DropDown_mzROIValueChanged(app, event)
            value_mzROI = app.DropDown_mzROI.Value;
            value_mzROI = evalin('base', value_mzROI);

            assignin('base','value_mzROI',value_mzROI);
            evalin('base','ROIMCR_identification.matrix.mzROI=value_mzROI;');
            evalin('base','clear value_mzROI');
        end

        % Value changed function: SignalacquisitionListBox
        function SignalacquisitionListBoxValueChanged(app, event)
            value_experiment = app.SignalacquisitionListBox.Value;
            if strcmp(value_experiment,'ms1') %Poner de default
                experiment = 1;
            elseif strcmp(value_experiment,'ms1 + ms2')
                experiment = 2;
            elseif strcmp(value_experiment,'ms1_p + ms2_p + ms1_n + ms2_n')
                experiment = 3;
            end
            
            assignin('base','experiment',experiment);
            evalin('base','ROIMCR_identification.experiment=experiment;');
            evalin('base','clear experiment');
        end

        % Button pushed function: mzROIextractionButton
        function mzROIextractionButtonPushed(app, event)
            experiment=evalin('base','ROIMCR_identification.experiment');

            if experiment == 1
                mzROI_extraction_1
            elseif experiment == 2
                mzROI_extraction_2
            elseif experiment == 3
                mzROI_extraction_3
            end
        end

        % Button pushed function: IdentificationsettingsButton
        function IdentificationsettingsButtonPushed(app, event)
            experiment=evalin('base','ROIMCR_identification.experiment');

            if experiment == 1
                identification_1
            elseif experiment == 2
                identification_2
            elseif experiment == 3
                identification_3
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            display('#################')
            display('#    RUNNING    #')
            display('#################')
            experiment=evalin('base','ROIMCR_identification.experiment');
            
            try
                if experiment == 1
                    sopt = evalin('base','ROIMCR_identification.matrix.st;');
                    mzROI = evalin('base', 'ROIMCR_identification.matrix.mzROI;');
                    threshold = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres;');
                    database = evalin('base','ROIMCR_identification.settings_identification.database;'); 
                    error = evalin('base','ROIMCR_identification.settings_identification.error;'); 
                    precursor = evalin('base','ROIMCR_identification.settings_identification.precursor');
                    ioni = evalin('base','ROIMCR_identification.settings_identification.ionization');


                    try
                        results = mz_extraction_roimcr_1(sopt,mzROI,threshold);

                        assignin('base','results',results);
                        evalin('base','ROIMCR_identification.ROIMCR_signals=results;');
                        evalin('base','clear results');
                    catch e1
                        if strcmp(e1.identifier, 'MATLAB:badsubscript') | strcmp(e1.identifier, 'MATLAB:structRefFromNonStruct')
                            errordlg('Make sure the ROIMCR results and mzROI extraction parameters are correct' , 'Error', 'modal');
                        else
                            rethrow(e1);
                        end
                    end

                    try
                        if ioni == 1
                            database = database([database.IONIZATION] == 'positive', :);
                        elseif ioni == 2
                            database = database([database.IONIZATION] == 'negative', :);
                        end

                        compounds = compound_identification_1(database, results, error, precursor);

                        assignin('base','compounds',compounds);
                        evalin('base','ROIMCR_identification.ROIMCR_identification=compounds;');
                    catch e1
                        if strcmp(e1.identifier, 'MATLAB:badsubscript') | strcmp(e1.identifier, 'MATLAB:structRefFromNonStruct')
                            errordlg('Make sure the ROIMCR results and mzROI extraction parameters are correct' , 'Error', 'modal');
                        else
                            rethrow(e1);
                        end
                    end

                elseif experiment == 2
                    sopt = evalin('base','ROIMCR_identification.matrix.st;');
                    mzROI = evalin('base', 'ROIMCR_identification.matrix.mzROI;');
                    threshold = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres;');
                    threshold_norm = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm;');
                    limit = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limit;');
                    database = evalin('base','ROIMCR_identification.settings_identification.database;'); 
                    error_ms1 = evalin('base','ROIMCR_identification.settings_identification.error_ms1;');
                    error_ms2 = evalin('base','ROIMCR_identification.settings_identification.error_ms2;'); 
                    precursor = evalin('base','ROIMCR_identification.settings_identification.precursor');
                    score = evalin('base','ROIMCR_identification.settings_identification.score;');
                    ioni = evalin('base','ROIMCR_identification.settings_identification.ionization');
                    
                    try
                        results = mz_extraction_roimcr_2(sopt,mzROI,limit,threshold, threshold_norm);

                        assignin('base','results',results);
                        evalin('base','ROIMCR_identification.ROIMCR_signals=results;');
                        evalin('base','clear results');
                    catch e1
                        if strcmp(e1.identifier, 'MATLAB:badsubscript') | strcmp(e1.identifier, 'MATLAB:structRefFromNonStruct')
                            errordlg('Make sure the ROIMCR results and mzROI extraction parameters are correct' , 'Error', 'modal');
                        else
                            rethrow(e1);
                        end
                    end

                    try
                        if ioni == 1
                            database = database([database.IONIZATION] == 'positive', :);
                        elseif ioni == 2
                            database = database([database.IONIZATION] == 'negative', :);
                        end

                        compounds = compound_identification_2(database, results, error_ms1, error_ms2, score, precursor);

                        assignin('base','compounds',compounds);
                        evalin('base','ROIMCR_identification.ROIMCR_identification=compounds;');
                    catch e1
                        if strcmp(e1.identifier,'MATLAB:structRefFromNonStruct')
                            errordlg({'Make sure the identification parameters are correct.','If the problem persist, It could be in mzROI extraction result.'} , 'Error', 'modal');
                        else
                            rethrow(e1)
                        end
                    end

                elseif experiment == 3
                    sopt = evalin('base','ROIMCR_identification.matrix.st;');
                    mzROI = evalin('base', 'ROIMCR_identification.matrix.mzROI;');
                    threshold = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres;');
                    threshold_norm = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm;');
                    limit_1 = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_1;');
                    limit_2 = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_2;');
                    limit_3 = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_3;');
                    limits = [limit_1, limit_2, limit_3];
                    database = evalin('base','ROIMCR_identification.settings_identification.database;');
                    ionization = evalin('base','ROIMCR_identification.settings_identification.ionization;'); 
                    error_ms1 = evalin('base','ROIMCR_identification.settings_identification.error_ms1;'); 
                    error_ms2 = evalin('base','ROIMCR_identification.settings_identification.error_ms2;');  
                    precursor = evalin('base','ROIMCR_identification.settings_identification.precursor');
                    score = evalin('base','ROIMCR_identification.settings_identification.score;');
                    ioni = evalin('base','ROIMCR_identification.settings_identification.ionization');

                    try
                        results = mz_extraction_roimcr_3(sopt,mzROI,limits,threshold, threshold_norm);

                        assignin('base','results',results);
                        evalin('base','ROIMCR_identification.ROIMCR_signals=results;');
                        evalin('base','clear results');
                    catch e1
                        if strcmp(e1.identifier, 'MATLAB:badsubscript') | strcmp(e1.identifier, 'MATLAB:structRefFromNonStruct')
                            errordlg('Make sure the ROIMCR results and mzROI extraction parameters are correct' , 'Error', 'modal');
                        else
                            rethrow(e1);
                        end
                    end
                    
                    try
                        if ioni == 1
                            database = database([database.IONIZATION] == 'positive', :);
                        elseif ioni == 2
                            database = database([database.IONIZATION] == 'negative', :);
                        end

                        compounds = compound_identification_3(database, results, error_ms1, error_ms2, score, ionization, precursor);
                        
                        assignin('base','compounds',compounds);
                        evalin('base','ROIMCR_identification.ROIMCR_identification=compounds;');
                    catch e1
                        if strcmp(e1.identifier,'MATLAB:structRefFromNonStruct')
                            errordlg({'Make sure the identification parameters are correct.','If the problem persist, It could be in mzROI extraction result.'} , 'Error', 'modal');
                        else
                            rethrow(e1)
                        end
                    end
                end
            catch e1
                rethrow(e1);
            end

        end

        % Button pushed function: ResultsvisualizationButton
        function ResultsvisualizationButtonPushed(app, event)
            experiment=evalin('base','ROIMCR_identification.experiment');

            if experiment == 1
                result_visualization_1
            else
                result_visualization_23
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.MSidenthomescreenUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MSidenthomescreenUIFigure = uifigure('Visible', 'off');
            app.MSidenthomescreenUIFigure.Name = 'MSident: home screen';
            app.generalGrid = uigridlayout(app.MSidenthomescreenUIFigure);
            app.title = uilabel(app.generalGrid);
            app.title.Text = 'Compound identification';
            app.ROIMCRresultsPanel = uipanel(app.generalGrid);
            app.ROIMCRresultsPanel.Title = 'ROIMCR results';
            app.MatrixSTLabel = uilabel(app.ROIMCRresultsPanel);
            app.MatrixSTLabel.Text = 'Matrix ST';
            app.mzROIarrayLabel = uilabel(app.ROIMCRresultsPanel);
            app.mzROIarrayLabel.Text = 'mzROI array';
            app.DropDown_ST = uidropdown(app.ROIMCRresultsPanel);
            app.DropDown_ST.Items = {'Select ST matrix'};
            app.DropDown_ST.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown_ST.ValueChangedFcn = createCallbackFcn(app, @DropDown_STValueChanged, true);
            app.DropDown_ST.Value = 'Select ST matrix';
            app.DropDown_mzROI = uidropdown(app.ROIMCRresultsPanel);
            app.DropDown_mzROI.Items = {'Select mzROI array'};
            app.DropDown_mzROI.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_mzROIOpening, true);
            app.DropDown_mzROI.ValueChangedFcn = createCallbackFcn(app, @DropDown_mzROIValueChanged, true);
            app.DropDown_mzROI.Value = 'Select mzROI array';
            app.ExperimenttypePanel = uipanel(app.generalGrid);
            app.ExperimenttypePanel.Title = 'Experiment type';
            app.SignalacquisitionLabel = uilabel(app.ExperimenttypePanel);
            app.SignalacquisitionLabel.Text = {'Signal '; 'acquisition'};
            app.SignalacquisitionListBox = uilistbox(app.ExperimenttypePanel);
            app.SignalacquisitionListBox.Items = {'ms1', 'ms1 + ms2', 'ms1_p + ms2_p + ms1_n + ms2_n'};
            app.SignalacquisitionListBox.ValueChangedFcn = createCallbackFcn(app, @SignalacquisitionListBoxValueChanged, true);
            app.SignalacquisitionListBox.Value = 'ms1';
            app.ParametersettingsPanel = uipanel(app.generalGrid);
            app.ParametersettingsPanel.Title = 'Parameter settings';
            app.mzROIextractionButton = uibutton(app.ParametersettingsPanel, 'push');
            app.mzROIextractionButton.ButtonPushedFcn = createCallbackFcn(app, @mzROIextractionButtonPushed, true);
            app.mzROIextractionButton.Text = {'mzROI '; 'extraction '};
            app.IdentificationsettingsButton = uibutton(app.ParametersettingsPanel, 'push');
            app.IdentificationsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentificationsettingsButtonPushed, true);
            app.IdentificationsettingsButton.Text = {'Identification'; 'settings'};
            app.RunButton = uibutton(app.generalGrid, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExitButton = uibutton(app.generalGrid, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ResultsvisualizationButton = uibutton(app.generalGrid, 'push');
            app.ResultsvisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsvisualizationButtonPushed, true);
            app.ResultsvisualizationButton.Text = {'Results'; 'visualization'};
            % Show the figure after all components are created
            app.MSidenthomescreenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_identification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSidenthomescreenUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSidenthomescreenUIFigure)
        end
    end
end