classdef identification_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Identificationparametersms1Panel  matlab.ui.container.Panel
        IonizactionofthesearchDropDownLabel  matlab.ui.control.Label
        IonizactionofthesearchDropDown  matlab.ui.control.DropDown
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Precursorthreshold01Label       matlab.ui.control.Label
        mzerrorppmLabel                 matlab.ui.control.Label
        DatabaseDropDown                matlab.ui.control.DropDown
        DatabaseDropDownLabel           matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','ROIMCR_identification.settings_identification.database=[];');
            evalin('base','ROIMCR_identification.settings_identification.databasename=[];');
            evalin('base','ROIMCR_identification.settings_identification.ionization=1;');
            evalin('base','ROIMCR_identification.settings_identification.error=10;');
            evalin('base','ROIMCR_identification.settings_identification.precursor=0.4;');
        end

        % Drop down opening function: DatabaseDropDown
        function DatabaseDropDownOpening(app, event)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            cell_array{1} = 'Select database';
            for i=2:length(vars)+1
                cell_array{i} = vars(i-1).name;
            end
            
            %Delete StartUp variable
            cell_array = erase(cell_array,'ROIMCR_identification');
            emptyCells = cellfun(@isempty, cell_array);
            nonEmptyCells = ~emptyCells;
            cell_array = cell_array(nonEmptyCells);

            app.DatabaseDropDown.Items = cell_array;
        end

        % Value changed function: DatabaseDropDown
        function DatabaseDropDownValueChanged(app, event)
            database = app.DatabaseDropDown.Value;
            database_name = app.DatabaseDropDown.Value;

            %store the database and its name 
            assignin('base','database_name',database_name)
            evalin('base','ROIMCR_identification.settings_identification.databasename=database_name;') 
            evalin('base','clear database_name');
            
            database = evalin('base', database);

            assignin('base','database_selected',database)
            evalin('base','ROIMCR_identification.settings_identification.database=database_selected;') 
            evalin('base','clear database_selected'); 
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            error = app.EditField.Value;
            
            assignin('base','error',error);
            evalin('base','ROIMCR_identification.settings_identification.error=error;'); 
            evalin('base','clear error');
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            precursor = app.EditField_2.Value;
            
            assignin('base','precursor',precursor);
            evalin('base','ROIMCR_identification.settings_identification.precursor=precursor;'); 
            evalin('base','clear precursor'); 
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Figurename = app.UIFigure ;
            close(Figurename)
        end

        % Value changed function: IonizactionofthesearchDropDown
        function IonizactionofthesearchDropDownValueChanged(app, event)
            ionization = app.IonizactionofthesearchDropDown.Value;
            
            if strcmp(ionization,'positive')
                ionization = 1;
            elseif strcmp(ionization,'negative')
                ionization = 2;
            end

            assignin('base','ionization',ionization);
            evalin('base','ROIMCR_identification.settings_identification.ionization=ionization;'); 
            evalin('base','clear ionization');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Identificationparametersms1Panel = uipanel(app.UIFigure);
            app.Identificationparametersms1Panel.Title = 'Identification parameters (ms1)';
            app.OKButton = uibutton(app.Identificationparametersms1Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DatabaseDropDownLabel = uilabel(app.Identificationparametersms1Panel);
            app.DatabaseDropDownLabel.Text = 'Database';
            app.DatabaseDropDown = uidropdown(app.Identificationparametersms1Panel);
            app.DatabaseDropDown.Items = {'Select database'};
            app.DatabaseDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DatabaseDropDownOpening, true);
            app.DatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @DatabaseDropDownValueChanged, true);
            app.DatabaseDropDown.Value = 'Select database';
            app.mzerrorppmLabel = uilabel(app.Identificationparametersms1Panel);
            app.mzerrorppmLabel.Text = 'm/z error (ppm)';
            app.Precursorthreshold01Label = uilabel(app.Identificationparametersms1Panel);
            app.Precursorthreshold01Label.Text = 'Precursor threshold (0-1)';
            app.EditField = uieditfield(app.Identificationparametersms1Panel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.Identificationparametersms1Panel, 'numeric');
            app.EditField_2.Limits = [0 1];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.IonizactionofthesearchDropDown = uidropdown(app.Identificationparametersms1Panel);
            app.IonizactionofthesearchDropDown.Items = {'positive', 'negative'};
            app.IonizactionofthesearchDropDown.ValueChangedFcn = createCallbackFcn(app, @IonizactionofthesearchDropDownValueChanged, true);
            app.IonizactionofthesearchDropDown.Value = 'positive';
            app.IonizactionofthesearchDropDownLabel = uilabel(app.Identificationparametersms1Panel);
            app.IonizactionofthesearchDropDownLabel.Text = {'Ionizaction of '; 'the search'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = identification_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end