classdef identification_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OKButton                        matlab.ui.control.Button
        Identificationparametersms1_pms2_pms1_nms2_nPanel  matlab.ui.container.Panel
        EditField_4                     matlab.ui.control.NumericEditField
        MS2mzerrorppmLabel              matlab.ui.control.Label
        IonizactionofthesearchDropDown  matlab.ui.control.DropDown
        IonizactionofthesearchDropDownLabel  matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        Scorethreshold01000Label        matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Precursorthreshold01Label       matlab.ui.control.Label
        MS1mzerrorppmLabel              matlab.ui.control.Label
        DatabaseDropDown                matlab.ui.control.DropDown
        DatabaseDropDownLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','ROIMCR_identification.settings_identification.database=[];');
            evalin('base','ROIMCR_identification.settings_identification.databasename=[];');
            evalin('base','ROIMCR_identification.settings_identification.ionization=1;');
            evalin('base','ROIMCR_identification.settings_identification.error_ms1=0;');
            evalin('base','ROIMCR_identification.settings_identification.error_ms2=1;');
            evalin('base','ROIMCR_identification.settings_identification.precursor=1;');
            evalin('base','ROIMCR_identification.settings_identification.score=0;');
        end

        % Drop down opening function: DatabaseDropDown
        function DatabaseDropDownOpening(app, event)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            cell_array{1} = 'Select database';
            for i=2:length(vars)+1
                cell_array{i} = vars(i-1).name;
            end
            
            %Delete StartUp variable
            cell_array = erase(cell_array,'ROIMCR_identification');
            emptyCells = cellfun(@isempty, cell_array);
            nonEmptyCells = ~emptyCells;
            cell_array = cell_array(nonEmptyCells);

            app.DatabaseDropDown.Items = cell_array;
        end

        % Value changed function: IonizactionofthesearchDropDown
        function IonizactionofthesearchDropDownValueChanged(app, event)
            ionization = app.IonizactionofthesearchDropDown.Value;
            
            if strcmp(ionization,'positive')
                ionization = 1;
            elseif strcmp(ionization,'negative')
                ionization = 2;
            end

            assignin('base','ionization',ionization);
            evalin('base','ROIMCR_identification.settings_identification.ionization=ionization;'); 
            evalin('base','clear ionization');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            error_ms1 = app.EditField.Value;
            
            assignin('base','error_ms1',error_ms1);
            evalin('base','ROIMCR_identification.settings_identification.error_ms1=error_ms1;'); 
            evalin('base','clear error_ms1');
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            error_ms2 = app.EditField_4.Value;
            
            assignin('base','error_ms2',error_ms2);
            evalin('base','ROIMCR_identification.settings_identification.error_ms2=error_ms2;'); 
            evalin('base','clear error_ms2');
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            precursor = app.EditField_2.Value;
            
            assignin('base','precursor',precursor);
            evalin('base','ROIMCR_identification.settings_identification.precursor=precursor;'); 
            evalin('base','clear precursor');
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            score = app.EditField_3.Value;
            
            assignin('base','score',score);
            evalin('base','ROIMCR_identification.settings_identification.score=score;'); 
            evalin('base','clear score');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Figurename = app.UIFigure ;
            close(Figurename)
        end

        % Value changed function: DatabaseDropDown
        function DatabaseDropDownValueChanged(app, event)
            database = app.DatabaseDropDown.Value;
            database_name = app.DatabaseDropDown.Value;

            %store the database and its name 
            assignin('base','database_name',database_name)
            evalin('base','ROIMCR_identification.settings_identification.databasename=database_name;') 
            evalin('base','clear database_name');

            database = evalin('base', database);

            assignin('base','database_selected',database)
            evalin('base','ROIMCR_identification.settings_identification.database=database_selected;') 
            evalin('base','clear database_selected');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Identificationparametersms1_pms2_pms1_nms2_nPanel = uipanel(app.UIFigure);
            app.Identificationparametersms1_pms2_pms1_nms2_nPanel.Title = 'Identification parameters (ms1_p+ms2_p + ms1_n+ms2_n)';
            app.DatabaseDropDownLabel = uilabel(app.Identificationparametersms1_pms2_pms1_nms2_nPanel);
            app.DatabaseDropDownLabel.Text = 'Database';
            app.DatabaseDropDown = uidropdown(app.Identificationparametersms1_pms2_pms1_nms2_nPanel);
            app.DatabaseDropDown.Items = {'Select database'};
            app.DatabaseDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DatabaseDropDownOpening, true);
            app.DatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @DatabaseDropDownValueChanged, true);
            app.DatabaseDropDown.Value = 'Select database';
            app.MS1mzerrorppmLabel = uilabel(app.Identificationparametersms1_pms2_pms1_nms2_nPanel);
            app.MS1mzerrorppmLabel.Text = 'MS1 m/z error (ppm)';
            app.Precursorthreshold01Label = uilabel(app.Identificationparametersms1_pms2_pms1_nms2_nPanel);
            app.Precursorthreshold01Label.Text = 'Precursor threshold (0-1)';
            app.EditField = uieditfield(app.Identificationparametersms1_pms2_pms1_nms2_nPanel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.Identificationparametersms1_pms2_pms1_nms2_nPanel, 'numeric');
            app.EditField_2.Limits = [0 1];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Scorethreshold01000Label = uilabel(app.Identificationparametersms1_pms2_pms1_nms2_nPanel);
            app.Scorethreshold01000Label.Text = 'Score threshold (0-1000)';
            app.EditField_3 = uieditfield(app.Identificationparametersms1_pms2_pms1_nms2_nPanel, 'numeric');
            app.EditField_3.Limits = [0 1000];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.IonizactionofthesearchDropDownLabel = uilabel(app.Identificationparametersms1_pms2_pms1_nms2_nPanel);
            app.IonizactionofthesearchDropDownLabel.Text = {'Ionizaction of '; 'the search'};
            app.IonizactionofthesearchDropDown = uidropdown(app.Identificationparametersms1_pms2_pms1_nms2_nPanel);
            app.IonizactionofthesearchDropDown.Items = {'positive', 'negative'};
            app.IonizactionofthesearchDropDown.ValueChangedFcn = createCallbackFcn(app, @IonizactionofthesearchDropDownValueChanged, true);
            app.IonizactionofthesearchDropDown.Value = 'positive';
            app.MS2mzerrorppmLabel = uilabel(app.Identificationparametersms1_pms2_pms1_nms2_nPanel);
            app.MS2mzerrorppmLabel.Text = 'MS2 m/z error (ppm)';
            app.EditField_4 = uieditfield(app.Identificationparametersms1_pms2_pms1_nms2_nPanel, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = identification_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end