classdef mzROI_extraction_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mzROIextractionms1UIFigure  matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        OKButton                    matlab.ui.control.Button
        EditField                   matlab.ui.control.NumericEditField
        Intensitythreshold01Label   matlab.ui.control.Label
    end

    
    properties (Access = public)
       selectedVal_mz1 = "def value";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres;');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            intensity_thres = app.EditField.Value;

            assignin('base','intensity_thres',intensity_thres);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres=intensity_thres;');
            evalin('base','clear intensity_thres');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Figurename = app.mzROIextractionms1UIFigure ;
            
            intensity_thres = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres;');
            selectedVal_mz1 = intensity_thres;
            setappdata(0, 'selectedVal_mz1', selectedVal_mz1);
            close(Figurename)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mzROIextractionms1UIFigure = uifigure('Visible', 'off');
            app.mzROIextractionms1UIFigure.Name = 'mzROI extraction (ms1)';
            app.Panel = uipanel(app.mzROIextractionms1UIFigure);
            app.Intensitythreshold01Label = uilabel(app.Panel);
            app.Intensitythreshold01Label.Text = 'Intensity threshold (0-1): ';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Limits = [0 1];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.1;
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.mzROIextractionms1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mzROI_extraction_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mzROIextractionms1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mzROIextractionms1UIFigure)
        end
    end
end