classdef mzROI_extraction_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mzROIextractionms1ms2UIFigure  matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        EditField_3                    matlab.ui.control.NumericEditField
        LimitLabel                     matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        Intensitythreshold01normalizedLabel  matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        Intensitythreshold01Label      matlab.ui.control.Label
    end

    
    properties (Access = public)
        selectedVal_mz2 = "def value";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres');
            app.EditField_2.Value = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm');
            app.EditField_3.Value = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limit');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            intensity_thres = app.EditField.Value;
            
            assignin('base','intensity_thres',intensity_thres);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres=intensity_thres;');
            evalin('base','clear intensity_thres');
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            intensity_thresh_norm = app.EditField_2.Value;
            
            assignin('base','intensity_thres_norm',intensity_thresh_norm);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm=intensity_thres_norm;');
            evalin('base','clear intensity_thres_norm');
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            limit_chooen = app.EditField_3.Value;
            
            assignin('base','limit_chooen',limit_chooen);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.limit=limit_chooen;');
            evalin('base','clear limit_chooen');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Figurename = app.mzROIextractionms1ms2UIFigure ;

            int_thres = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres;');
            int_thres_n = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm;');
            limit = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limit;');

            selectedVal_mz2{1} = num2str(int_thres);
            selectedVal_mz2{2} = num2str(int_thres_n);
            selectedVal_mz2{3} = num2str(limit);
            
            setappdata(0, 'selectedVal_mz2', selectedVal_mz2);

            close(Figurename)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mzROIextractionms1ms2UIFigure = uifigure('Visible', 'off');
            app.mzROIextractionms1ms2UIFigure.Name = 'mzROI extraction (ms1 + ms2)';
            app.Panel = uipanel(app.mzROIextractionms1ms2UIFigure);
            app.Intensitythreshold01Label = uilabel(app.Panel);
            app.Intensitythreshold01Label.Text = 'Intensity threshold (0-1): ';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Limits = [0 1];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.1;
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Intensitythreshold01normalizedLabel = uilabel(app.Panel);
            app.Intensitythreshold01normalizedLabel.Text = {'Intensity threshold (0-1)'; '(normalized) '};
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.Limits = [0 1];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 0.1;
            app.Label = uilabel(app.Panel);
            app.Label.Text = ':';
            app.LimitLabel = uilabel(app.Panel);
            app.LimitLabel.Text = 'Limit:';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            % Show the figure after all components are created
            app.mzROIextractionms1ms2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mzROI_extraction_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mzROIextractionms1ms2UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mzROIextractionms1ms2UIFigure)
        end
    end
end