classdef mzROI_extraction_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mzROIextractionms1_pms2_pms1_nms2_nUIFigure  matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        EditField_5                matlab.ui.control.NumericEditField
        Limit3Label                matlab.ui.control.Label
        EditField_4                matlab.ui.control.NumericEditField
        Limit2Label                matlab.ui.control.Label
        EditField_3                matlab.ui.control.NumericEditField
        Limit1Label                matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        Intensitythreshold01normalizedLabel  matlab.ui.control.Label
        OKButton                   matlab.ui.control.Button
        EditField                  matlab.ui.control.NumericEditField
        Intensitythreshold01Label  matlab.ui.control.Label
    end

    
    properties (Access = public)
        selectedVal_mz3 = "def value";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres;');
            app.EditField_2.Value = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm;');
            app.EditField_3.Value = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_1;');
            app.EditField_4.Value = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_2;');
            app.EditField_5.Value = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_3;');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            intensity_thres = app.EditField.Value;
            
            assignin('base','intensity_thres',intensity_thres);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres=intensity_thres;');
            evalin('base','clear intensity_thres');
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            intensity_thresh_norm = app.EditField_2.Value;
            
            assignin('base','intensity_thres_norm',intensity_thresh_norm);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm=intensity_thres_norm;');
            evalin('base','clear intensity_thres_norm');
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            limit_1 = app.EditField_3.Value;
            
            assignin('base','limit_1_choosen',limit_1);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_1=limit_1_choosen;');
            evalin('base','clear limit_1_choosen');
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
%             limits = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits');
            limit_2 = app.EditField_4.Value;
%             limits = [limits,value];
%             limits = sort(limits);
            
            assignin('base','limit_2_choosen',limit_2);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_2=limit_2_choosen;');
            evalin('base','clear limit_2_choosen');
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
%             limits = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits');
            limit_3 = app.EditField_5.Value;
%             limits = [limits,value];
%             limits = sort(limits);
            
            assignin('base','limit_3_choosen',limit_3);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_3=limit_3_choosen;');
            evalin('base','clear limit_3_choosen');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Figurename = app.mzROIextractionms1_pms2_pms1_nms2_nUIFigure ;

            int_thres = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres;');
            int_thres_n = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm;');
            limit1 = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_1;');
            limit2 = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_2;');
            limit3 = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits_3;');
            limits = strcat(num2str(limit1), '-', num2str(limit2), '-', num2str(limit3));

            selectedVal_mz3{1} = num2str(int_thres);
            selectedVal_mz3{2} = num2str(int_thres_n);
            selectedVal_mz3{3} = limits;
            
            setappdata(0, 'selectedVal_mz3', selectedVal_mz3);

            close(Figurename)
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mzROIextractionms1_pms2_pms1_nms2_nUIFigure = uifigure('Visible', 'off');
            app.mzROIextractionms1_pms2_pms1_nms2_nUIFigure.Name = 'mzROI extraction (ms1_p+ms2_p + ms1_n+ms2_n)';
            app.Panel = uipanel(app.mzROIextractionms1_pms2_pms1_nms2_nUIFigure);
            app.Intensitythreshold01Label = uilabel(app.Panel);
            app.Intensitythreshold01Label.Text = 'Intensity threshold (0-1):';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Limits = [0 1];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.1;
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Intensitythreshold01normalizedLabel = uilabel(app.Panel);
            app.Intensitythreshold01normalizedLabel.Text = {'Intensity threshold (0-1)'; '(normalized) '};
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.Limits = [0 1];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 0.1;
            app.Label = uilabel(app.Panel);
            app.Label.Text = ':';
            app.Limit1Label = uilabel(app.Panel);
            app.Limit1Label.Text = 'Limit 1:';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.Limit2Label = uilabel(app.Panel);
            app.Limit2Label.Text = 'Limit 2:';
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.Limit3Label = uilabel(app.Panel);
            app.Limit3Label.Text = 'Limit 3:';
            app.EditField_5 = uieditfield(app.Panel, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            % Show the figure after all components are created
            app.mzROIextractionms1_pms2_pms1_nms2_nUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mzROI_extraction_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mzROIextractionms1_pms2_pms1_nms2_nUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mzROIextractionms1_pms2_pms1_nms2_nUIFigure)
        end
    end
end